classdef MainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewMenu_2                   matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SaveasMenu                  matlab.ui.container.Menu
        CSVMenu                     matlab.ui.container.Menu
        MatMenu                     matlab.ui.container.Menu
        PDFMenu                     matlab.ui.container.Menu
        ExitMenu                    matlab.ui.container.Menu
        ROIMenu                     matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        DROIMenu                    matlab.ui.container.Menu
        AutotrackingMenu            matlab.ui.container.Menu
        EditselectedMenu            matlab.ui.container.Menu
        DeleteselectedMenu          matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        Panel                       matlab.ui.container.Panel
        SliceSliderLabel            matlab.ui.control.Label
        SliceSlider                 matlab.ui.control.Slider
        FrameSliderLabel            matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        UIAxes                      matlab.ui.control.UIAxes
        FrameField                  matlab.ui.control.NumericEditField
        SliceField                  matlab.ui.control.NumericEditField
        Panel_2                     matlab.ui.container.Panel
        BtnZoomIn                   matlab.ui.control.Button
        BtnGammaCorShow             matlab.ui.control.Button
        BtnZoomOut                  matlab.ui.control.Button
        BtnHistEq                   matlab.ui.control.Button
        BtnContinueROI              matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        MetadataPanel               matlab.ui.container.Panel
        ROIinformationPanel         matlab.ui.container.Panel
        AveragepixelvalueEditField  matlab.ui.control.EditField
        AveragepixelvalueEditFieldLabel  matlab.ui.control.Label
        StandarddeviationEditFieldLabel  matlab.ui.control.Label
        StandarddeviationEditField  matlab.ui.control.EditField
        TabGroup                    matlab.ui.container.TabGroup
        RelativeBOLDTab             matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        BaselineEditFieldLabel      matlab.ui.control.Label
        BaselineEditField           matlab.ui.control.NumericEditField
        AbsoluteBOLDTab             matlab.ui.container.Tab
        UIAxes2_2                   matlab.ui.control.UIAxes
        Button                      matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        GammacorrectionPanel        matlab.ui.container.Panel
        KoefficientLabel            matlab.ui.control.Label
        KoefficientSlider           matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
    end

    
    properties (Access = public)
        numberOfFrames;
        numberOfSlices;
        dicomDisplay;
        contrastAdjuster;
        dicomFile;  
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dicomDisplay = DicomDisplay;
            app.dicomDisplay = app.dicomDisplay.Constructor();
            app.contrastAdjuster = ContrastAdjuster;
            app.dicomFile = DICOM_File_DTO;
        end

        % Button pushed function: Button_2, Button_3
        function Button_2Pushed(app, event)
            I=imshow('ClearAll_24.png','parent',app.UIAxes);
            app1;
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key, 'rightarrow') == 1 && app.FrameSlider.Value < max(app.FrameSlider.Limits)
                app.FrameSlider.Value = app.FrameSlider.Value + 1;
                app.FrameField.Value = app.FrameField.Value + 1;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.UIAxes);
            end
            
            if strcmp(key, 'leftarrow') == 1 && app.FrameSlider.Value > min(app.FrameSlider.Limits)
                app.FrameSlider.Value = app.FrameSlider.Value - 1;
                app.FrameField.Value = app.FrameField.Value - 1;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.UIAxes);
            end
            
            if strcmp(key, 'uparrow') == 1 && app.SliceSlider.Value < max(app.SliceSlider.Limits)
                app.SliceSlider.Value = app.SliceSlider.Value + 1;
                app.SliceField.Value = app.SliceField.Value + 1;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.UIAxes);
            end
            
            if strcmp(key, 'downarrow') == 1 && app.SliceSlider.Value > min(app.SliceSlider.Limits)
                app.SliceSlider.Value = app.SliceSlider.Value - 1;
                app.SliceField.Value = app.SliceField.Value - 1;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.UIAxes);
            end
              
        end

        % Menu selected function: OpenMenu
        function OpenDialogPress(app, event)
            folder_path = uigetdir();
           % paths = dir(fullfile(folder_path,'*.dcm')); %Vi skal lige være sikre på formatet, da filerne vi har fået ikke umiddelbart er .dcm 
            [app.dicomFile, app.dicomDisplay, app.numberOfFrames, app.numberOfSlices] = app.dicomDisplay.ReadDicomFiles(folder_path);
            app.FrameSlider.Limits = [1 app.numberOfFrames];
            app.SliceSlider.Limits = [1 app.numberOfSlices];
            imshow(app.dicomFile.pixelData,'parent',app.UIAxes);
        end

        % Button pushed function: BtnZoomIn
        function BtnZoomInButtonPushed(app, event)
            
        end

        % Button pushed function: BtnZoomOut
        function BtnZoomOutButtonPushed(app, event)
            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.FrameField.Value = round(app.FrameSlider.Value);
            app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceField.Value, app.FrameField.Value);
            imshow(app.dicomFile.pixelData,'parent',app.UIAxes);
        end

        % Value changed function: FrameField
        function FrameFieldValueChanged(app, event)
            if(app.FrameField.Value <= max(app.FrameSlider.Limits) && app.FrameField.Value >= min(app.FrameSlider.Limits))
                app.FrameSlider.Value = app.FrameField.Value;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.UIAxes);
            else
                app.FrameField.Value = app.FrameSlider.Value;
            end
            
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            app.SliceField.Value = round(app.SliceSlider.Value);
            app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceField.Value, app.FrameField.Value);
            imshow(app.dicomFile.pixelData,'parent',app.UIAxes);     
        end

        % Value changed function: SliceField
        function SliceFieldValueChanged(app, event)
            if(app.SliceField.Value <= max(app.SliceSlider.Limits) && app.SliceField.Value >= min(app.SliceSlider.Limits))
                app.SliceSlider.Value = app.SliceField.Value;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.UIAxes);
            else
                app.SliceField.Value = app.SliceSlider.Value;
            end
        end

        % Button pushed function: BtnGammaCorShow
        function BtnGammaCorShowButtonPushed(app, event)
            
        end

        % Button pushed function: BtnHistEq
        function BtnHistEqButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu_2 = uimenu(app.FileMenu);
            app.NewMenu_2.Accelerator = 'N';
            app.NewMenu_2.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDialogPress, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as';
            app.CSVMenu = uimenu(app.SaveasMenu);
            app.CSVMenu.Text = 'CSV';
            app.MatMenu = uimenu(app.SaveasMenu);
            app.MatMenu.Text = 'Mat';
            app.PDFMenu = uimenu(app.SaveasMenu);
            app.PDFMenu.Text = 'PDF';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Accelerator = 'q';
            app.ExitMenu.Text = 'Exit';
            app.ROIMenu = uimenu(app.UIFigure);
            app.ROIMenu.Text = 'ROI';
            app.NewMenu = uimenu(app.ROIMenu);
            app.NewMenu.Text = 'New';
            app.DROIMenu = uimenu(app.ROIMenu);
            app.DROIMenu.Checked = 'on';
            app.DROIMenu.Text = '3D ROI';
            app.AutotrackingMenu = uimenu(app.ROIMenu);
            app.AutotrackingMenu.Checked = 'on';
            app.AutotrackingMenu.Text = 'Autotracking';
            app.EditselectedMenu = uimenu(app.ROIMenu);
            app.EditselectedMenu.Enable = 'off';
            app.EditselectedMenu.Separator = 'on';
            app.EditselectedMenu.Text = 'Edit selected';
            app.DeleteselectedMenu = uimenu(app.ROIMenu);
            app.DeleteselectedMenu.Enable = 'off';
            app.DeleteselectedMenu.Text = 'Delete selected';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.Panel = uipanel(app.UIFigure);
            app.SliceSliderLabel = uilabel(app.Panel);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.Panel);
            app.SliceSlider.Limits = [1 100];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.Value = 1;
            app.FrameSliderLabel = uilabel(app.Panel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.Panel);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Value = 1;
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.FrameField = uieditfield(app.Panel, 'numeric');
            app.FrameField.ValueChangedFcn = createCallbackFcn(app, @FrameFieldValueChanged, true);
            app.FrameField.Value = 1;
            app.SliceField = uieditfield(app.Panel, 'numeric');
            app.SliceField.ValueChangedFcn = createCallbackFcn(app, @SliceFieldValueChanged, true);
            app.SliceField.Value = 1;
            app.Panel_2 = uipanel(app.Panel);
            app.BtnZoomIn = uibutton(app.Panel_2, 'push');
            app.BtnZoomIn.ButtonPushedFcn = createCallbackFcn(app, @BtnZoomInButtonPushed, true);
            app.BtnZoomIn.Text = '';
            app.BtnGammaCorShow = uibutton(app.Panel_2, 'push');
            app.BtnGammaCorShow.ButtonPushedFcn = createCallbackFcn(app, @BtnGammaCorShowButtonPushed, true);
            app.BtnGammaCorShow.Text = '';
            app.BtnZoomOut = uibutton(app.Panel_2, 'push');
            app.BtnZoomOut.ButtonPushedFcn = createCallbackFcn(app, @BtnZoomOutButtonPushed, true);
            app.BtnZoomOut.Text = '';
            app.BtnHistEq = uibutton(app.Panel_2, 'push');
            app.BtnHistEq.ButtonPushedFcn = createCallbackFcn(app, @BtnHistEqButtonPushed, true);
            app.BtnHistEq.Text = '';
            app.BtnContinueROI = uibutton(app.Panel_2, 'push');
            app.BtnContinueROI.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ROI'; 'Color'; 'Include in analysis'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.MetadataPanel = uipanel(app.UIFigure);
            app.MetadataPanel.Title = 'Metadata';
            app.ROIinformationPanel = uipanel(app.UIFigure);
            app.ROIinformationPanel.Title = 'ROI information';
            app.AveragepixelvalueEditField = uieditfield(app.ROIinformationPanel, 'text');
            app.AveragepixelvalueEditField.Editable = 'off';
            app.AveragepixelvalueEditFieldLabel = uilabel(app.ROIinformationPanel);
            app.AveragepixelvalueEditFieldLabel.Text = 'Average pixel value';
            app.StandarddeviationEditFieldLabel = uilabel(app.ROIinformationPanel);
            app.StandarddeviationEditFieldLabel.Text = 'Standard deviation';
            app.StandarddeviationEditField = uieditfield(app.ROIinformationPanel, 'text');
            app.StandarddeviationEditField.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RelativeBOLDTab = uitab(app.TabGroup);
            app.RelativeBOLDTab.Title = 'Relative BOLD';
            app.UIAxes2 = uiaxes(app.RelativeBOLDTab);
            title(app.UIAxes2, 'BOLD Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.BaselineEditFieldLabel = uilabel(app.RelativeBOLDTab);
            app.BaselineEditFieldLabel.Text = 'Baseline';
            app.BaselineEditField = uieditfield(app.RelativeBOLDTab, 'numeric');
            app.AbsoluteBOLDTab = uitab(app.TabGroup);
            app.AbsoluteBOLDTab.Title = 'Absolute BOLD';
            app.UIAxes2_2 = uiaxes(app.AbsoluteBOLDTab);
            title(app.UIAxes2_2, 'BOLD Signal')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'ClearAll_24.png';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'settings_grey_192x192.png';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.GammacorrectionPanel = uipanel(app.UIFigure);
            app.GammacorrectionPanel.Title = 'Gamma correction';
            app.GammacorrectionPanel.Visible = 'off';
            app.KoefficientLabel = uilabel(app.GammacorrectionPanel);
            app.KoefficientLabel.Text = 'Koefficient:';
            app.KoefficientSlider = uislider(app.GammacorrectionPanel);
            app.Label = uilabel(app.GammacorrectionPanel);
            app.Label.Text = '';
            app.Switch = uiswitch(app.GammacorrectionPanel, 'slider');
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainWindow

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end